% description: Copyright 2020 The MathWorks, Inc.
classdef TiLeds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VirtualLEDhardwareUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLEDsGroup_1             matlab.ui.container.GridLayout
        LED_11                      matlab.ui.control.Lamp
        LED_12                      matlab.ui.control.Lamp
        LED_13                      matlab.ui.control.Lamp
        LED_14                      matlab.ui.control.Lamp
        LED_15                      matlab.ui.control.Lamp
        LED_16                      matlab.ui.control.Lamp
        LED_G1_V18                  matlab.ui.control.Label
        LED_G1_V0                   matlab.ui.control.Label
        GridLEDsGroup_2             matlab.ui.container.GridLayout
        LED_21                      matlab.ui.control.Lamp
        LED_22                      matlab.ui.control.Lamp
        LED_23                      matlab.ui.control.Lamp
        LED_24                      matlab.ui.control.Lamp
        LED_25                      matlab.ui.control.Lamp
        LED_26                      matlab.ui.control.Lamp
        LED_G2_V0                   matlab.ui.control.Label
        LED_G2_V18                  matlab.ui.control.Label
        GridLEDsGroup_3             matlab.ui.container.GridLayout
        LED_31                      matlab.ui.control.Lamp
        LED_32                      matlab.ui.control.Lamp
        LED_33                      matlab.ui.control.Lamp
        LED_34                      matlab.ui.control.Lamp
        LED_35                      matlab.ui.control.Lamp
        LED_36                      matlab.ui.control.Lamp
        LED_G3_V0                   matlab.ui.control.Label
        LED_G3_V18                  matlab.ui.control.Label
        GridLEDsGroup_4             matlab.ui.container.GridLayout
        LED_41                      matlab.ui.control.Lamp
        LED_42                      matlab.ui.control.Lamp
        LED_43                      matlab.ui.control.Lamp
        LED_44                      matlab.ui.control.Lamp
        LED_45                      matlab.ui.control.Lamp
        LED_46                      matlab.ui.control.Lamp
        LED_G4_V0                   matlab.ui.control.Label
        LED_G4_V18                  matlab.ui.control.Label
        GridLEDsGroup_5             matlab.ui.container.GridLayout
        LED_51                      matlab.ui.control.Lamp
        LED_52                      matlab.ui.control.Lamp
        LED_53                      matlab.ui.control.Lamp
        LED_54                      matlab.ui.control.Lamp
        LED_55                      matlab.ui.control.Lamp
        LED_56                      matlab.ui.control.Lamp
        LED_G5_V0                   matlab.ui.control.Label
        LED_G5_V18                  matlab.ui.control.Label
        GridLEDsGroup_6             matlab.ui.container.GridLayout
        LED_61                      matlab.ui.control.Lamp
        LED_62                      matlab.ui.control.Lamp
        LED_63                      matlab.ui.control.Lamp
        LED_64                      matlab.ui.control.Lamp
        LED_65                      matlab.ui.control.Lamp
        LED_66                      matlab.ui.control.Lamp
        LED_G6_V0                   matlab.ui.control.Label
        LED_G6_V18                  matlab.ui.control.Label
    end

  properties (Access = private)
    NbLEDs = 6;
    NbGroups = 6;
  end
  
  properties (Access = public)
    MinCurrent = 5;
    MaxCurrent = 100;
    LEDsInitColor = [0,0,0]; % Black
  end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Class TiLeds
% Goal  : This class contains all properties and methods needed to instanciate
%         and use an App UI component implementing group of LEDs.
%         To instanciate and use the class, run a script such as the one in the
%         "Component" folder called "TestTiLeds.m"
% Copyright 2020 The MathWorks, Inc.
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

  % Methods of the class that can be accessed within the object
  methods (Access = public)
    function setTiLeds(app,ledCurrent,groupID)
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Function setTiLeds()
    % Goal  : Set function to change the intensity of the TI LEDs
    % IN    : - ledCurrent: value that represents the light intensity [0-100] mA 
    %         - groupID   : ID of the LEDs group to update
    % IN/OUT: - app       : object of the class
    % OUT   : -
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      % Light intensity mapping: [0-100] mA <=> 0.00-1.00 Brightness level
      intensity = ones(1,3)*min([ledCurrent app.MaxCurrent]/app.MaxCurrent);
      if (intensity > app.MinCurrent/app.MaxCurrent)
        % Update intensity of each LED from the group
        for m=1:1:app.NbLEDs
          evalc(['app.LED_' num2str(groupID) num2str(m) '.Color = [' num2str(intensity) ']']);
        end
      end
    end

    function resetTiLeds(app)
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Function resetTiLeds()
    % Goal  : reset function to reset the color of the TI LEDs
    % IN    : 
    % IN/OUT: - app: object of the class
    % OUT   : -
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      startupFcn(app);
    end
  end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      % Initialize the background color of each LED from each group
      for n=1:1:app.NbGroups
        for m=1:1:app.NbLEDs
          evalc(['app.LED_' num2str(n) num2str(m) '.Color = app.LEDsInitColor']);
        end
      end
      
        end

        % Close request function: VirtualLEDhardwareUIFigure
        function VirtualLEDhardwareUIFigureCloseRequest(app, event)
      delete(app);
      clc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VirtualLEDhardwareUIFigure = uifigure('Visible', 'off');
            app.VirtualLEDhardwareUIFigure.Name = 'Virtual LED hardware';
            app.VirtualLEDhardwareUIFigure.CloseRequestFcn = createCallbackFcn(app, @VirtualLEDhardwareUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.VirtualLEDhardwareUIFigure);
            app.GridLEDsGroup_1 = uigridlayout(app.GridLayout);
            app.LED_11 = uilamp(app.GridLEDsGroup_1);
            app.LED_12 = uilamp(app.GridLEDsGroup_1);
            app.LED_13 = uilamp(app.GridLEDsGroup_1);
            app.LED_14 = uilamp(app.GridLEDsGroup_1);
            app.LED_15 = uilamp(app.GridLEDsGroup_1);
            app.LED_16 = uilamp(app.GridLEDsGroup_1);
            app.LED_G1_V18 = uilabel(app.GridLEDsGroup_1);
            app.LED_G1_V18.Text = '';
            app.LED_G1_V0 = uilabel(app.GridLEDsGroup_1);
            app.LED_G1_V0.Text = '';
            app.GridLEDsGroup_2 = uigridlayout(app.GridLayout);
            app.LED_21 = uilamp(app.GridLEDsGroup_2);
            app.LED_22 = uilamp(app.GridLEDsGroup_2);
            app.LED_23 = uilamp(app.GridLEDsGroup_2);
            app.LED_24 = uilamp(app.GridLEDsGroup_2);
            app.LED_25 = uilamp(app.GridLEDsGroup_2);
            app.LED_26 = uilamp(app.GridLEDsGroup_2);
            app.LED_G2_V0 = uilabel(app.GridLEDsGroup_2);
            app.LED_G2_V0.Text = '';
            app.LED_G2_V18 = uilabel(app.GridLEDsGroup_2);
            app.LED_G2_V18.Text = '';
            app.GridLEDsGroup_3 = uigridlayout(app.GridLayout);
            app.LED_31 = uilamp(app.GridLEDsGroup_3);
            app.LED_32 = uilamp(app.GridLEDsGroup_3);
            app.LED_33 = uilamp(app.GridLEDsGroup_3);
            app.LED_34 = uilamp(app.GridLEDsGroup_3);
            app.LED_35 = uilamp(app.GridLEDsGroup_3);
            app.LED_36 = uilamp(app.GridLEDsGroup_3);
            app.LED_G3_V0 = uilabel(app.GridLEDsGroup_3);
            app.LED_G3_V0.Text = '';
            app.LED_G3_V18 = uilabel(app.GridLEDsGroup_3);
            app.LED_G3_V18.Text = '';
            app.GridLEDsGroup_4 = uigridlayout(app.GridLayout);
            app.LED_41 = uilamp(app.GridLEDsGroup_4);
            app.LED_42 = uilamp(app.GridLEDsGroup_4);
            app.LED_43 = uilamp(app.GridLEDsGroup_4);
            app.LED_44 = uilamp(app.GridLEDsGroup_4);
            app.LED_45 = uilamp(app.GridLEDsGroup_4);
            app.LED_46 = uilamp(app.GridLEDsGroup_4);
            app.LED_G4_V0 = uilabel(app.GridLEDsGroup_4);
            app.LED_G4_V0.Text = '';
            app.LED_G4_V18 = uilabel(app.GridLEDsGroup_4);
            app.LED_G4_V18.Text = '';
            app.GridLEDsGroup_5 = uigridlayout(app.GridLayout);
            app.LED_51 = uilamp(app.GridLEDsGroup_5);
            app.LED_52 = uilamp(app.GridLEDsGroup_5);
            app.LED_53 = uilamp(app.GridLEDsGroup_5);
            app.LED_54 = uilamp(app.GridLEDsGroup_5);
            app.LED_55 = uilamp(app.GridLEDsGroup_5);
            app.LED_56 = uilamp(app.GridLEDsGroup_5);
            app.LED_G5_V0 = uilabel(app.GridLEDsGroup_5);
            app.LED_G5_V0.Text = '';
            app.LED_G5_V18 = uilabel(app.GridLEDsGroup_5);
            app.LED_G5_V18.Text = '';
            app.GridLEDsGroup_6 = uigridlayout(app.GridLayout);
            app.LED_61 = uilamp(app.GridLEDsGroup_6);
            app.LED_62 = uilamp(app.GridLEDsGroup_6);
            app.LED_63 = uilamp(app.GridLEDsGroup_6);
            app.LED_64 = uilamp(app.GridLEDsGroup_6);
            app.LED_65 = uilamp(app.GridLEDsGroup_6);
            app.LED_66 = uilamp(app.GridLEDsGroup_6);
            app.LED_G6_V0 = uilabel(app.GridLEDsGroup_6);
            app.LED_G6_V0.Text = '';
            app.LED_G6_V18 = uilabel(app.GridLEDsGroup_6);
            app.LED_G6_V18.Text = '';
            % Show the figure after all components are created
            app.VirtualLEDhardwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TiLeds

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VirtualLEDhardwareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VirtualLEDhardwareUIFigure)
        end
    end
end